classdef CompSim_FinalProjectGUI_Combo < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        FinalProjectUIFigure      matlab.ui.Figure
        GridLayout                matlab.ui.container.GridLayout
        LeftPanel                 matlab.ui.container.Panel
        Panel2                    matlab.ui.container.Panel
        ALabel                    matlab.ui.control.Label
        JLabel                    matlab.ui.control.Label
        CalculateButton           matlab.ui.control.Button
        MaxCurentRippleLabel      matlab.ui.control.Label
        MaxCurentRippleEditField  matlab.ui.control.NumericEditField
        EnergyGeneratedbytheFuelCellsEditFieldLabel  matlab.ui.control.Label
        EnergyGeneratedbytheFuelCellsEditField  matlab.ui.control.NumericEditField
        DutyCycleSliderLabel      matlab.ui.control.Label
        DutyCycleSlider           matlab.ui.control.Slider
        SettingsLabel             matlab.ui.control.Label
        EndTimefrom0sLabel        matlab.ui.control.Label
        EndTimefrom0sSlider       matlab.ui.control.Slider
        ResultsLabel              matlab.ui.control.Label
        LoadPowerEditFieldLabel   matlab.ui.control.Label
        LoadPowerEditField        matlab.ui.control.NumericEditField
        WLabel                    matlab.ui.control.Label
        Label_2                   matlab.ui.control.Label
        Panel1SimulationofmultDERislandedMGwithACbus  matlab.ui.container.Panel
        aWindLabel                matlab.ui.control.Label
        PCWind                    matlab.ui.control.NumericEditField
        bSolarRadiationLabel      matlab.ui.control.Label
        PCSolar                   matlab.ui.control.NumericEditField
        SystemDisturbanceInputsPowerChangeofLabel  matlab.ui.control.Label
        SettingsLabel_2           matlab.ui.control.Label
        ResultsLabel_2            matlab.ui.control.Label
        BES0DropDownLabel         matlab.ui.control.Label
        BES0DropDown              matlab.ui.control.DropDown
        BES0DropDown_2Label       matlab.ui.control.Label
        BES0DropDown_2            matlab.ui.control.DropDown
        AbbreviationsGuideLabel   matlab.ui.control.Label
        Label                     matlab.ui.control.Label
        JLabel_2                  matlab.ui.control.Label
        JLabel_3                  matlab.ui.control.Label
        CalculateButton_2         matlab.ui.control.Button
        RightPanel                matlab.ui.container.Panel
        TabGroup                  matlab.ui.container.TabGroup
        MGSimulation1Tab          matlab.ui.container.Tab
        GridLayout2               matlab.ui.container.GridLayout
        UIAxes2AllEnergyChange    matlab.ui.control.UIAxes
        UIAxesAllPowerChange      matlab.ui.control.UIAxes
        MGSimulation2Tab          matlab.ui.container.Tab
        WTG                       matlab.ui.control.UIAxes
        PV                        matlab.ui.control.UIAxes
        MT                        matlab.ui.control.UIAxes
        FC                        matlab.ui.control.UIAxes
        BES                       matlab.ui.control.UIAxes
        f                         matlab.ui.control.UIAxes
        FCSimulationResult1Tab    matlab.ui.container.Tab
        EulersMethodUIAxes        matlab.ui.control.UIAxes
        ode45UIAxes               matlab.ui.control.UIAxes
        EulerZoomSwitch           matlab.ui.control.Switch
        ode45ZoomSwitch           matlab.ui.control.Switch
        FCSimulationResult2Tab    matlab.ui.container.Tab
        CompareVoltagesUIAxes     matlab.ui.control.UIAxes
        CurrentRipplesUIAxes      matlab.ui.control.UIAxes
        TwoMethodVoltage          matlab.ui.control.Switch
        TwoMethodCurrent          matlab.ui.control.Switch
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    methods (Access = private)
    % Yiwen Jia
    % This section contains some user defined functions to assist the calculations in some of the callback functions
        function [t_Euler, t_45, iL1_Euler, iL1_45, iL2_Euler, iL2_45, vo_Euler, vo_45, iFC_Euler, iFC_45, vFC_Euler, vFC_45] = ODESolver(app)
        % This function solves the ODEs of the FC system using both Euler method and ode45()
        % Outputs: t_Euler, t_45 - the independent vectors calculated by the two methods
        %          iL1_Euler, iL1_45 - the current throught the inductor L1 calculated by the two methods
        %          iL2_Euler, iL2_45 - the current throught the inductor L2 calculated by the two methods
        %          vo_Euler, vo_45 - the voltage over the load calcualted by the two methods
        %          iFC_Euler, iFC_45 - the current throught the fuel cells calculated by the two methods
        %          vFC_Euler, vFC_45 - the voltage across the fuel cells calculated by the two methods
        
            % Define the constants. All the values comes from [1]
            Ncell = 72;    % number of fuel cells used to supply energy
            T = 338.15;    % temperature inside the fuel cells while they are working, in K
            PH2 = 1.494*101325;   % partial pressure of H2, Pa
            PO2 = 0.21*101325;    % partial pressure of O2, Pa
            A = 0.055;     % Tafel slope, together with the following two constants are used to calculate the deltaV_act, in V
            i0 = 1.5e-3;   % exchange current, in A
            in = 0.13;     % internal current, in A
            Rohm = 0.006;  % resistance of the electrode, in Ohms
            L = 0.68e-3;   % inductance of the two inductors, in H
            RL = 0.1;      % resistance of inductors, in Ohms
            C = 330e-6;    % capacity of the capacitor, in F
            Ts = 8e-5;     % operation period, in s
            D = app.DutyCycleSlider.Value;       % duty cycle
            loadPower = app.LoadPowerEditField.Value; % output power ranging from 0W to 1000W. User input on GUI.

            % Define help functions. The piecewise functions discribes the chart in Figure 2. Other help functions are provided in [1]
            q1 = @(t) 1*double(t>=0 && t<D*Ts) + 0*double(t>=D*Ts && t<Ts);
            q2 = @(t) 1*double((t>=0 && t<(D-0.5)*Ts) || (t>=Ts/2 && t<Ts)) + 0*double(t>=(D-0.5)*Ts && t<Ts/2);
            V_Nernst = 1.482-0.00485*T-0.0000431*T*log(PH2*PO2);
            del_V_act = @(iL1, iL2) A*log((iL1+iL2+in)/i0);
            del_V_Ohm = @(iL1, iL2) Rohm*(iL1+iL2);
            V_FC = @(iL1, iL2) Ncell*(V_Nernst-del_V_act(iL1, iL2)-del_V_Ohm(iL1, iL2));
            io = 0; 
            % Define the ODEs. depV = [iL1; iL2; vo], and d_depV = [diL1/dt; diL2/dt; dvo/dt].
            d_depV = @(t, depV) [(V_FC(depV(1), depV(2))-RL*depV(1)-(1-q1(mod(t, Ts)))*depV(3))/L;
                                 (V_FC(depV(1), depV(2))-RL*depV(2)-(1-q2(mod(t, Ts)))*depV(3))/L; 
                                 ((1-q1(mod(t, Ts)))*depV(1)+(1-q2(mod(t, Ts)))*depV(2)-io)/C];
            
            %*************** Solve the ODEs using Euler's wmethod ********************************************************
            % Initialize variables  
            h = 8e-7;   % time step size
            t_end = app.EndTimefrom0sSlider.Value;  % ending time, user input
            t_Euler = 0:h:t_end;   % output t_Euler
            depV0 = [0.0; 0.0; 0.0]; % initial conditions: iL1 = 0, iL2 = 0, vo = 0
            depV_Euler = zeros(length(depV0), length(t_Euler)); % initialize the solution
            depV_Euler(:, 1) = depV0; % set the initial conditions to the solution
            for i = 1:length(t_Euler)-1
                % set the value of io 
                if(abs(depV_Euler(3,i)) < 1e-10)
                    io = 0;
                else
                    io = loadPower/depV_Euler(3,i);
                end
                % calculate every column of depV based on the previous column of depV
                depV_Euler(:, i+1) = depV_Euler(:, i) + d_depV(t_Euler(i), depV_Euler(:, i))*h; 
            end
            
            %*************** Solve the ODEs using ode45() *******************************************************************
            % Locate the first value of vo that is nonzero, then set the values of the dependent variables 
            % at that location as the initial conditions, and set the start time at that location too. 
            vo_Euler = depV_Euler(3, :);
            index_voNonZero = find(abs(vo_Euler) > 0, 1)
            depV0_45 = depV_Euler(:, index_voNonZero); 
            depV0_45 = imagToReal(app, depV0_45);
            startTime_45 = h*(index_voNonZero-1);
            % Solve the ODEs
            [t_45, depV_45] = ode45(d_depV, [startTime_45 t_end], depV0_45);   
            
            depV_Euler = depV_Euler'; % turn results to column vectors
            
            %*************** Outputs *****************************************************************************************
            % Extract outputs from the resulting matrices
            iL1_Euler = depV_Euler(:, 1);
            iL2_Euler = depV_Euler(:, 2);
            vo_Euler = depV_Euler(:, 3);
            iL1_45 = depV_45(:, 1);
            iL2_45 = depV_45(:, 2);
            vo_45 = depV_45(:, 3);
            % Calculate the magnitude of every imaginary element with their signs unchanged.
            iL1_Euler = imagToReal(app, iL1_Euler);
            iL2_Euler = imagToReal(app, iL2_Euler);
            vo_Euler = imagToReal(app, vo_Euler);
            iL1_45 = imagToReal(app, iL1_45);
            iL2_45 = imagToReal(app, iL2_45);
            vo_45 = imagToReal(app, vo_45);
            % Compute the rest of the outputs.
            iFC_Euler = iL1_Euler+iL2_Euler;
            vFC_Euler = V_FC(iL1_Euler, iL2_Euler);
            iFC_45 = iL1_45+iL2_45;
            vFC_45 = V_FC(iL1_45, iL2_45); 
        end
        
        %********* User defined function: Calculate max. current ripple *********************************************************
        function [maxRipple, t_max, maxes, t_min, mins] = maxCurrentRipple(app, data, t)
        % This function calculates the maximum ripple of the given data.
        % Inputs:  data      - the corresponding dependent vector with ripple data points
        %          t         - given independent vector
        %          h         - the step size of t
        % Outputs: maxRipple - the maximum ripple of data
        %          t_max     - a vector of independent values at local maximas
        %          maxes     - a vector of local maximas
        %          t_min     - a vector of independent values at local minimas
        %          mins      - a vector of local minimas
            
            maxIndex = 0;  % initiate index number of the vector of maximas
            minIndex = 0;  % initiate index number of the vector of minimas
            % calculate both the forward and backward derivatives on points from the 2nd through the 2nd last one using vectorization method
            forwardDer = (data(3:end)-data(2:end-1))./(t(3:end)-t(2:end-1));  
            backwardDer = (data(2:end-1)-data(1:end-2))./(t(2:end-1)-t(1:end-2));  
            for i = 1:length(forwardDer)
                % add elements to local maxima vectors
                if (forwardDer(i)<0 && backwardDer(i)>0)  
                    maxIndex = maxIndex + 1;
                    maxes(maxIndex) = data(i+1);
                    t_max(maxIndex) = t(i+1);
                % add elements to local minima vectors
                elseif (forwardDer(i)>0 && backwardDer(i)<0)  % Create a vector of minimas
                    minIndex = minIndex + 1;
                    mins(minIndex) = data(i+1);
                    t_min(minIndex) = t(i+1);
                end
            end
            % Calculate all the differences between every adjacent local maximas and minimas, and place them in one vector.
            if (length(maxes) > length(mins))
                ripple = [maxes(1:end-1)-mins, maxes(2:end)-mins]; 
            elseif (length(maxes) < length(mins))
                ripple = [maxes-mins(1:end-1), maxes-mins(2:end)]; 
            else
                if(t_max(1) < t_min(1))
                    ripple = [maxes-mins, maxes(2:end)-mins(1:end-1)];
                elseif (t_max(1) > t_min(1))
                    ripple = [maxes-mins, maxes(1:end-1)-mins(2:end)];
                end
            end
            
            maxRipple = max(ripple);  % Find the maximum current ripple, A. Displayed on GUI
        end
        
        %********* User defined function: Select data from t > 0.01s ********************************************************* 
        function [T, IL1, IL2, Ifc, Vo] = selectData(app, t, numOfData, iL1, iL2, vo)
        % This is a helper function, selecting certain amount of data from the given vectors, then storing them new vectors.
        % Inputs: t, iL1, iL2, vo - vectors where data is selected from
        %         numOfData - number of data selected
        % Outputs: T, IL1, IL2, Vo - new vectors of selected data from the respective given vectors
        %          Ifc - the sum of IL1 and IL2.
        
            index = find(t>0.01, 1); % find the index of the start of the stable state of the system
            % select numOfData amount of data from each given vectors.
            T = t(index:index+numOfData);
            IL1 = iL1(index:index+numOfData);
            IL2 = iL2(index:index+numOfData);
            Ifc = IL1+IL2;
            Vo = vo(index:index+numOfData);
        end
        
        %********* User defined function: Calculate the magnitude of the i *********************************************************
        function realVector = imagToReal(app, imag)
        % This function translates a vector of imaginary numbers to the vector of their respective magnitudes, with signs unchanged
            for i = 1:length(imag)
                if(real(imag(i)) >= 0)
                    imag(i) = abs(imag(i));
                else
                    imag(i) = -abs(imag(i));
                end
            end
            realVector = imag; % Output the result
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.FinalProjectUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {715, 715};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {499, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
        % Yiwen Jia
        % This function simulates the system of FC, and outputs the results
        
            [t_Euler, t_45, iL1_Euler, iL1_45, iL2_Euler, iL2_45, vo_Euler, vo_45, iFC_Euler, iFC_45, vFC_Euler, vFC_45] = ODESolver(app);
      
            % Plot the results calculated by Euler's method
            yyaxis(app.EulersMethodUIAxes, 'left')
            plot(app.EulersMethodUIAxes, t_Euler, iFC_Euler); hold on
            title(app.EulersMethodUIAxes, "Results Integrated by Euler's Method");
            xlabel(app.EulersMethodUIAxes, 'Time (s)');
            ylabel(app.EulersMethodUIAxes, 'Current generated by the fuel cells (A)');
            yyaxis(app.EulersMethodUIAxes, 'right') 
            plot(app.EulersMethodUIAxes, t_Euler, vo_Euler); 
            ylabel(app.EulersMethodUIAxes, 'Voltage of the load (V)');
            legend(app.EulersMethodUIAxes, {'iFC', 'v out'}, 'Location',"best");
            
            % Plot the results calculated by ode45()            
            yyaxis(app.ode45UIAxes, "left")
            plot(app.ode45UIAxes, t_45, iFC_45); hold on
            title(app.ode45UIAxes, 'Results Integrated by ode45()');
            xlabel(app.ode45UIAxes, 'Time (s)');
            ylabel(app.ode45UIAxes, 'Current generated by the fuel cells (A)');
            yyaxis(app.ode45UIAxes, "right") 
            plot(app.ode45UIAxes, t_45, vo_45); hold off
            ylabel(app.ode45UIAxes, 'Voltage of the load (V)');
            legend(app.ode45UIAxes, {'iFC', 'v out'},'Location',"best");            
            
            % Compare the voltage output from the fuel cells (vFC) and the voltage over the load (vo)
            plot(app.CompareVoltagesUIAxes, t_Euler, [abs(vFC_Euler), abs(vo_Euler)]);
            title(app.CompareVoltagesUIAxes, 'Compare Vfc and Vo (Euler)');
            xlabel(app.CompareVoltagesUIAxes, 'Time (s)');
            ylabel(app.CompareVoltagesUIAxes, 'Voltage (V)');
            legend(app.CompareVoltagesUIAxes, {'Vfc - source', 'Vo - load'}, 'Location', 'best');
            
            % Display the current ripples on the selected data
            % Select 300 tabulated data points from results derived from ode45
            [T, IL1, IL2, Ifc, Vo] = selectData(app, t_Euler, 300, iL1_Euler, iL2_Euler, vo_Euler);
            % Calculate the local maximas and minimas 
            [maxRipple, t_localMax, localMaxes, t_localMin, localMins] = maxCurrentRipple(app, Ifc, T);
            % Plot the selected data and the local maximas and minimas
            plot(app.CurrentRipplesUIAxes, T, [IL1, IL2, Ifc], '-', t_localMax, localMaxes, 'mo', t_localMin, localMins, 'g*');
            title(app.CurrentRipplesUIAxes, 'Current ripples and local maximas and minimas (Euler)');
            xlabel(app.CurrentRipplesUIAxes, 'Time (s)');
            ylabel(app.CurrentRipplesUIAxes, 'Current (A)');
            legend(app.CurrentRipplesUIAxes, 'IL1', 'IL2', 'Ifc', 'maximas', 'minimas');
            
            % Post Data Process
            % Compute the power generated by the fuel cells, and calculate the energy generated over time T using trapz()
            power = abs(vFC_45).*abs(iFC_45);  % Watts
            energyInput = trapz(t_45, power); % J. Value displayed on GUI
            app.EnergyGeneratedbytheFuelCellsEditField.Value = energyInput;
            % Calculate the maximum current ripple during the steady state (t > 0.008s, 1000 data points)
            index = find(t_45>0.008, 1);
            t_post = t_45(index:index+1000);
            iFC_post = iFC_45(index:index+1000);
            [max_deltaIfc, t_max, maxes, t_min, mins] = maxCurrentRipple(app, iFC_post, t_post);
            app.MaxCurentRippleEditField.Value = abs(max_deltaIfc);
            
            % Reset the control switches of the app
            app.EulerZoomSwitch.Value = 0;
            app.ode45ZoomSwitch.Value = 0;
            app.TwoMethodVoltage.Value = 0;
            app.TwoMethodCurrent.Value = 0;

        end

        % Value changed function: EulerZoomSwitch
        function EulerZoomSwitchValueChanged(app, event)
        % Yiwen Jia
        % This function enables the switch next to the 1st chart on the tab
        % FC-Simulation Result 1 to toggle between the zoomed-in (state
        % value = 1) and zoomed-out (state value = 0) versions of the data
        % calculated by Euler's method
            
            value = app.EulerZoomSwitch.Value;
            [t_Euler, t_45, iL1_Euler, iL1_45, iL2_Euler, iL2_45, vo_Euler, vo_45, iFC_Euler, iFC_45, vFC_Euler, vFC_45] = ODESolver(app);
            if (value == 0)
                % Plot the results calculated by Euler's method (zoom out)
                yyaxis(app.EulersMethodUIAxes, 'left')
                plot(app.EulersMethodUIAxes, t_Euler, iFC_Euler); hold on
                ylabel(app.EulersMethodUIAxes, 'Current generated by the fuel cells (A)');
                yyaxis(app.EulersMethodUIAxes, 'right') 
                plot(app.EulersMethodUIAxes, t_Euler, vo_Euler); hold off
                ylabel(app.EulersMethodUIAxes, 'Voltage of the load (V)');
            elseif (value == 1)
                % Select zoomed-in tabulated data from Euler's results
                [T, IL1, IL2, Ifc, Vo] = selectData(app, t_Euler, 300, iL1_Euler, iL2_Euler, vo_Euler);
                % plot the zoomed-in data
                yyaxis(app.EulersMethodUIAxes, 'left') 
                plot(app.EulersMethodUIAxes, T, Ifc,'b-'); hold on
                ylabel(app.EulersMethodUIAxes, 'Current generated by the fuel cells (A)');           
                yyaxis(app.EulersMethodUIAxes, 'right') 
                plot(app.EulersMethodUIAxes, T, Vo); hold off
                ylabel(app.EulersMethodUIAxes, 'Voltage across the load (V)');
            end
            title(app.EulersMethodUIAxes, "Results Integrated by Euler's Method");
            xlabel(app.EulersMethodUIAxes, 'Time (s)');
            legend(app.EulersMethodUIAxes, {'iFC', 'v out'}, 'Location',"best");
        end

        % Value changed function: ode45ZoomSwitch
        function ode45ZoomSwitchValueChanged(app, event)
        % Yiwen Jia
        % This function enables the switch next to the 2nd chart on the tab
        % FC-Simulation Result 1 to toggle between the zoomed-in (state
        % value = 1) and zoomed-out (state value = 0) versions of the data
        % calculated by ode45()
        
            value = app.ode45ZoomSwitch.Value;
            [t_Euler, t_45, iL1_Euler, iL1_45, iL2_Euler, iL2_45, vo_Euler, vo_45, iFC_Euler, iFC_45, vFC_Euler, vFC_45] = ODESolver(app);
            if (value == 0)
                % Plot the results calculated by ode45() (zoom out)
                yyaxis(app.ode45UIAxes, "left")
                plot(app.ode45UIAxes, t_45, iFC_45); hold on
                ylabel(app.ode45UIAxes, 'Current generated by the fuel cells (A)');
                yyaxis(app.ode45UIAxes, "right") 
                plot(app.ode45UIAxes, t_45, abs(vo_45)); hold off
                ylabel(app.ode45UIAxes, 'Voltage of the load (V)');
            elseif (value == 1)
                % Select tabulated data from ode45's results (zoom in)
                [T, IL1, IL2, Ifc, Vo] = selectData(app, t_45, 300, iL1_45, iL2_45, vo_45);
                % Plot tabulated data from ode45's results (zoom in)
                yyaxis(app.ode45UIAxes, 'left') 
                plot(app.ode45UIAxes, T, Ifc,'b-'); hold on
                ylabel(app.ode45UIAxes, 'Current generated by the fuel cells (A)');           
                yyaxis(app.ode45UIAxes, 'right') 
                plot(app.ode45UIAxes, T, Vo);
                ylabel(app.ode45UIAxes, 'Voltage across the load (V)');
            end
            title(app.ode45UIAxes, 'Results Integrated by ode45()');
            xlabel(app.ode45UIAxes, 'Time (s)');
            legend(app.ode45UIAxes, {'iFC', 'v out'},'Location',"best");
        end

        % Value changed function: TwoMethodVoltage
        function TwoMethodVoltageValueChanged(app, event)
        % Yiwen Jia
        % This function enables the switch next to the 1st chart on the tab
        % FC-Simulation Result 2 to toggle between the voltages calculated
        % by Euler's method (state value = 0) and by ode45() (state value = 1)
            
            value = app.TwoMethodVoltage.Value;
            [t_Euler, t_45, iL1_Euler, iL1_45, iL2_Euler, iL2_45, vo_Euler, vo_45, iFC_Euler, iFC_45, vFC_Euler, vFC_45] = ODESolver(app);            
            % Define the data under different state values of the switch
            if (value == 1)
                T = t_45;
                vFC = vFC_45;
                vo = vo_45;
                title(app.CompareVoltagesUIAxes, 'Compare Vfc and Vo (ode45)');
            elseif  (value == 0)
                T = t_Euler;
                vFC = vFC_Euler;
                vo = vo_Euler;
                title(app.CompareVoltagesUIAxes, 'Compare Vfc and Vo (Euler)');
            end
            % Plot the data
            plot(app.CompareVoltagesUIAxes, T, [abs(vFC), abs(vo)]);
            xlabel(app.CompareVoltagesUIAxes, 'Time (s)');
            ylabel(app.CompareVoltagesUIAxes, 'Voltage (V)');
            legend(app.CompareVoltagesUIAxes, {'Vfc - source', 'Vo - load'}, 'Location', 'best');
            
        end

        % Value changed function: TwoMethodCurrent
        function TwoMethodCurrentValueChanged(app, event)
        % Yiwen Jia
        % This function enables the switch next to the 2nd chart on the tab
        % FC-Simulation Result 2 to toggle between the currents calculated
        % by Euler's method (state value = 0) and by ode45() (state value = 1)
        
            value = app.TwoMethodCurrent.Value;
            [t_Euler, t_45, iL1_Euler, iL1_45, iL2_Euler, iL2_45, vo_Euler, vo_45, iFC_Euler, iFC_45, vFC_Euler, vFC_45] = ODESolver(app);
            % Display the current ripples on the selected data
            % Select 300 tabulated data points from results calcualted by both methods
            if (value == 1)
                [T, IL1, IL2, Ifc, Vo] = selectData(app, t_45, 300, iL1_45, iL2_45, vo_45);
                title(app.CurrentRipplesUIAxes, 'Current ripples and local maximas and minimas (ode45)');
            elseif (value == 0)
                [T, IL1, IL2, Ifc, Vo] = selectData(app, t_Euler, 300, iL1_Euler, iL2_Euler, vo_Euler);
                title(app.CurrentRipplesUIAxes, 'Current ripples and local maximas and minimas (Euler)');
            end
            % Calculate the local maximas and minimas 
            [maxRipple, t_localMax, localMaxes, t_localMin, localMins] = maxCurrentRipple(app, Ifc, T);
            % Plot the selected data and the local maximas and minimas
            plot(app.CurrentRipplesUIAxes, T, [IL1, IL2, Ifc], '-', t_localMax, localMaxes, 'mo', t_localMin, localMins, 'g*');            
            xlabel(app.CurrentRipplesUIAxes, 'Time (s)');
            ylabel(app.CurrentRipplesUIAxes, 'Current (A)');
            legend(app.CurrentRipplesUIAxes, 'IL1', 'IL2', 'Ifc', 'maximas', 'minimas');
        end

        % Button pushed function: CalculateButton_2
        function CalculateButton_2Pushed(app, event)
    % Hanne Nicolaisen
    % Microgrid Simulation
    % Final Project

% Simulation Parameters
T_wtg = 1.5;
T_pv = 1.8;
T_mt = 2;
T_fc = 1.5;
T_bes = 0.1;
D = 0.012;
M = 0.2;
% Parameters unused but provided by [6]
% a = 1;
% b = 0.8;
% gamma1 = 0.02;

u = .008; % control input
tspan = [0, 10]; % time interval

Pw = app.PCWind.Value; % User determines power change of wind 
Pphi = app.PCSolar.Value; % User determines power change of solar radiation

% System of ODEs
dpdt = @(t,P) [((-1/T_wtg)*P(1) + (1/T_wtg)*Pw);
                    ((-1/T_pv)*P(2) + (1/T_pv)*Pphi);
                    ((-1/T_mt)*P(3) + (1/T_mt)*u);
                    ((-1/T_fc)*P(4) + (1/T_fc)*u);
                    ((-1/T_bes)*P(5) + ((1/T_bes)*P(6)));
                    ((-2*D/M)*P(6) -(2/M)*(P(1)+P(2)+P(3)+P(4)+P(5)+P(6)))];
               
% Inital Values 
% P0 = [P_wtg, P_pv, P_mt, P_fc, P_bes, f]'; This is key for inputs
P0 = [.5, .8, .2, .4, 4, 1]';


% solve ODE using userdefined function odeEueler
[t,P] = odeEuler(dpdt, tspan, .1, P0);

% plot solution for T_wtg
plot(app.WTG,t,P(:,1))

% plot solution for T_pv
plot(app.PV,t,P(:,2))

% plot solution for T_mt
plot(app.MT,t,P(:,3))

% plot the solution for T_fc
plot(app.FC,t,P(:,4))

% plot solution for T_bes
plot(app.BES,t,P(:,5))

% plot solution for f
plot(app.f,t,P(:,6))

% plot change in power all together
plot(app.UIAxesAllPowerChange, t, P(:,1));
hold(app.UIAxesAllPowerChange,'on');
plot(app.UIAxesAllPowerChange,t,P(:,2));
hold(app.UIAxesAllPowerChange,'on');
plot(app.UIAxesAllPowerChange,t,P(:,3));
hold(app.UIAxesAllPowerChange,'on');
plot(app.UIAxesAllPowerChange,t,P(:,4));
hold(app.UIAxesAllPowerChange,'on');
plot(app.UIAxesAllPowerChange,t,P(:,5));
hold(app.UIAxesAllPowerChange,'on');
plot(app.UIAxesAllPowerChange,t,P(:,6));
hold(app.UIAxesAllPowerChange,'off');
legend(app.UIAxesAllPowerChange, '\DeltaWTG (J) ','\DeltaPV (J)','\DeltaMT (J)','\DeltaFC (J)','\DeltaBES (J)','\Deltaf (Hz)','Location','northeast');

% solve for integral, or total energy using trapezoidal rule 
EnergyofWTP = TrapezoidalIntegration(P(:,1),tspan,.1);
EnergyofPV = TrapezoidalIntegration(P(:,2),tspan,.1);
EnergyofMT = TrapezoidalIntegration(P(:,3),tspan,.1);
EnergyofFC = TrapezoidalIntegration(P(:,4),tspan,.1);
EnergyofBES = TrapezoidalIntegration(P(:,5),tspan,.1);

% plot the integrals together as bar graphs
bar(app.UIAxes2AllEnergyChange, 1, EnergyofWTP);
hold(app.UIAxes2AllEnergyChange,'on');
bar(app.UIAxes2AllEnergyChange, 2, EnergyofPV);
hold(app.UIAxes2AllEnergyChange,'on');
bar(app.UIAxes2AllEnergyChange, 3, EnergyofMT);
hold(app.UIAxes2AllEnergyChange,'on');
bar(app.UIAxes2AllEnergyChange, 4, EnergyofFC);
hold(app.UIAxes2AllEnergyChange,'on');
bar(app.UIAxes2AllEnergyChange, 5, EnergyofBES);
hold(app.UIAxes2AllEnergyChange,'off');
set(app.UIAxes2AllEnergyChange,'XTickLabel',[]);
legend(app.UIAxes2AllEnergyChange,'\DeltaWTG','\DeltaPV','\DeltaMT','\DeltaFC','\DeltaBES','Location','northeast');


%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% User defined functions %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%


% User defined function odeEuler implements Euler's method to estimate the solution of a vector-valued ODE.
% Inputs:
%   f - a handle to a MATLAB function of the form dydx = f(x,y) that defines the ODE system, where x is a scalar and 
% both y and dydx are column vectors.
%   range - the range of independent variables over which the simulation will be performed [initial-value, final-value]
%   h - step size
%   y0 - column vector representing the initial-value(s)
% Outputs:
%   x - row vector of x-points (independent variable) in the approximate solution.
%   y - solution array. Each row in y corresponds to the solution(s) at the x-value in the corresponding element of x.
%       y(i,:) corresponds to the solution(s) at x(i)
function [x,y] = odeEuler(f,range,h,y0)

% Initialize the output solution arrays.
x = range(1):h:range(2);
y = zeros(length(y0),length(x));
y(:,1) = y0;

% Compute x and y using Euler's method.
for i = 1:length(x) - 1
    y(:, i+1) = y(:,i) + h*f(x(i),y(:,i));
end

x = x';
y = y';     % output y as a series of column vectors.

end

% User defined function TrapezoidalIntegration computes the trapezoidal rule using an anonymous function
%       Inputs: - ftn: the function handle to the anonymous function
%               - limits: the limits of integration (a vector of two terms)
%               - subIntervals: the number of subintervals to use in the integration
%       Outputs: - NumIntegral: the numerical integratl of the function over the limits provided using the
%                  trapezoidal rule as a scalar value
function [NumIntegral] = TrapezoidalIntegration(ftn,limits,subIntervals)

lower =  limits(:,1)+1; % lower limit
upper = limits(:,2); % upper limit

%fplot(ftn,[lower,upper]); title('plot'); xlabel('x'),ylabel('ftn(x)') % plot of function over range specified by limits
%hold on; plot([lower,upper],[ftn(lower),ftn(upper)]); hold off

h = (upper-lower)/subIntervals; % delta x = h, the width of each subinterval
Trap = (ftn(lower) + ftn(upper))/2; % first and last term

TrapSum = 0; % Initialize the summation
for i = 1:subIntervals-1 % initialize the index variable, increment by 1 until subintervals-1
    TrapSum = TrapSum + ftn(lower+i*h); % take initial value and adding the value of the function xi (applying xi directly into ftn call)
end

NumIntegral = h*(Trap + TrapSum); % summing the two terms together and multiplying by h

end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.FinalProjectUIFigure = uifigure('Visible', 'off');
            app.FinalProjectUIFigure.Name = 'Simulations of Dynamic Systems';
            app.FinalProjectUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.FinalProjectUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.Panel2 = uipanel(app.LeftPanel);
            app.Panel2.Tooltip = {''};
            app.Panel2.Title = 'Panel 2. Simulation of circuit with interleaved boost dc-dc converter';
            app.ALabel = uilabel(app.Panel2);
            app.ALabel.Text = 'A';
            app.JLabel = uilabel(app.Panel2);
            app.JLabel.Text = 'J';
            app.CalculateButton = uibutton(app.Panel2, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.MaxCurentRippleLabel = uilabel(app.Panel2);
            app.MaxCurentRippleLabel.Text = 'Max.Curent Ripple';
            app.MaxCurentRippleEditField = uieditfield(app.Panel2, 'numeric');
            app.EnergyGeneratedbytheFuelCellsEditFieldLabel = uilabel(app.Panel2);
            app.EnergyGeneratedbytheFuelCellsEditFieldLabel.Text = 'Energy Generated by the Fuel Cells';
            app.EnergyGeneratedbytheFuelCellsEditField = uieditfield(app.Panel2, 'numeric');
            app.EnergyGeneratedbytheFuelCellsEditField.ValueDisplayFormat = '%6.4g';
            app.DutyCycleSliderLabel = uilabel(app.Panel2);
            app.DutyCycleSliderLabel.Text = 'Duty Cycle';
            app.DutyCycleSlider = uislider(app.Panel2);
            app.DutyCycleSlider.Limits = [0.51 0.8];
            app.DutyCycleSlider.Value = 0.6;
            app.SettingsLabel = uilabel(app.Panel2);
            app.SettingsLabel.Text = 'Settings ';
            app.EndTimefrom0sLabel = uilabel(app.Panel2);
            app.EndTimefrom0sLabel.Text = 'End Time (from 0s)';
            app.EndTimefrom0sSlider = uislider(app.Panel2);
            app.EndTimefrom0sSlider.Limits = [0.02 0.08];
            app.EndTimefrom0sSlider.MajorTicks = [0.001 0.02 0.04 0.06 0.08];
            app.EndTimefrom0sSlider.MajorTickLabels = {'0.001', '0.02', '0.04', '0.06', '0.08'};
            app.EndTimefrom0sSlider.Value = 0.03;
            app.ResultsLabel = uilabel(app.Panel2);
            app.ResultsLabel.Text = 'Results';
            app.LoadPowerEditFieldLabel = uilabel(app.Panel2);
            app.LoadPowerEditFieldLabel.Text = 'Load Power';
            app.LoadPowerEditField = uieditfield(app.Panel2, 'numeric');
            app.LoadPowerEditField.Limits = [0 1000];
            app.LoadPowerEditField.Value = 600;
            app.WLabel = uilabel(app.Panel2);
            app.WLabel.Text = 'W';
            app.Label_2 = uilabel(app.Panel2);
            app.Label_2.Text = '(0, 1000)';
            app.Panel1SimulationofmultDERislandedMGwithACbus = uipanel(app.LeftPanel);
            app.Panel1SimulationofmultDERislandedMGwithACbus.Title = 'Panel 1: Simulation of mult-DER islanded MG with AC bus';
            app.aWindLabel = uilabel(app.Panel1SimulationofmultDERislandedMGwithACbus);
            app.aWindLabel.Text = 'a. Wind';
            app.PCWind = uieditfield(app.Panel1SimulationofmultDERislandedMGwithACbus, 'numeric');
            app.bSolarRadiationLabel = uilabel(app.Panel1SimulationofmultDERislandedMGwithACbus);
            app.bSolarRadiationLabel.Text = 'b. Solar Radiation';
            app.PCSolar = uieditfield(app.Panel1SimulationofmultDERislandedMGwithACbus, 'numeric');
            app.SystemDisturbanceInputsPowerChangeofLabel = uilabel(app.Panel1SimulationofmultDERislandedMGwithACbus);
            app.SystemDisturbanceInputsPowerChangeofLabel.Text = {'System Disturbance Inputs'; ''; 'Power Change of:'};
            app.SettingsLabel_2 = uilabel(app.Panel1SimulationofmultDERislandedMGwithACbus);
            app.SettingsLabel_2.Text = 'Settings ';
            app.ResultsLabel_2 = uilabel(app.Panel1SimulationofmultDERislandedMGwithACbus);
            app.ResultsLabel_2.Text = 'Results';
            app.BES0DropDownLabel = uilabel(app.Panel1SimulationofmultDERislandedMGwithACbus);
            app.BES0DropDownLabel.Text = 'BES > 0';
            app.BES0DropDown = uidropdown(app.Panel1SimulationofmultDERislandedMGwithACbus);
            app.BES0DropDown.Items = {'If BES > 0, then the amount of power supplied to the battery is greater than the power drawn from it. The excess power is stored in the battery.'};
            app.BES0DropDown.Tooltip = {''};
            app.BES0DropDown.Value = 'If BES > 0, then the amount of power supplied to the battery is greater than the power drawn from it. The excess power is stored in the battery.';
            app.BES0DropDown_2Label = uilabel(app.Panel1SimulationofmultDERislandedMGwithACbus);
            app.BES0DropDown_2Label.Text = 'BES < 0';
            app.BES0DropDown_2 = uidropdown(app.Panel1SimulationofmultDERislandedMGwithACbus);
            app.BES0DropDown_2.Items = {'If BES < 0, the battery''s maximum capacity has been reached and excess power is wasted.'};
            app.BES0DropDown_2.Value = 'If BES < 0, the battery''s maximum capacity has been reached and excess power is wasted.';
            app.AbbreviationsGuideLabel = uilabel(app.Panel1SimulationofmultDERislandedMGwithACbus);
            app.AbbreviationsGuideLabel.Text = 'Abbreviations Guide';
            app.Label = uilabel(app.Panel1SimulationofmultDERislandedMGwithACbus);
            app.Label.Text = {'WTG = Wind Turbine Generator'; ''; 'PV = Photovoltaic Generator'; ''; 'FC = Fuel Cell'; ''; 'MT = Micro Turbine'; ''; 'BES = Battery Energy Storage'; ''; 'f =  The AC Bus Frequency Deviation'};
            app.JLabel_2 = uilabel(app.Panel1SimulationofmultDERislandedMGwithACbus);
            app.JLabel_2.Text = 'J';
            app.JLabel_3 = uilabel(app.Panel1SimulationofmultDERislandedMGwithACbus);
            app.JLabel_3.Text = 'J';
            app.CalculateButton_2 = uibutton(app.Panel1SimulationofmultDERislandedMGwithACbus, 'push');
            app.CalculateButton_2.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_2Pushed, true);
            app.CalculateButton_2.Text = 'Calculate';
            app.RightPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.RightPanel);
            app.MGSimulation1Tab = uitab(app.TabGroup);
            app.MGSimulation1Tab.Title = 'MG - Simulation 1';
            app.GridLayout2 = uigridlayout(app.MGSimulation1Tab);
            app.UIAxes2AllEnergyChange = uiaxes(app.GridLayout2);
            title(app.UIAxes2AllEnergyChange, 'Total Energy Transferred/Used')
            xlabel(app.UIAxes2AllEnergyChange, 'Source')
            ylabel(app.UIAxes2AllEnergyChange, 'J')
            zlabel(app.UIAxes2AllEnergyChange, 'Z')
            app.UIAxesAllPowerChange = uiaxes(app.GridLayout2);
            title(app.UIAxesAllPowerChange, 'Total Power Generation/Consumption ')
            xlabel(app.UIAxesAllPowerChange, 'Time (s)')
            ylabel(app.UIAxesAllPowerChange, 'Hz or J')
            zlabel(app.UIAxesAllPowerChange, 'Z')
            app.MGSimulation2Tab = uitab(app.TabGroup);
            app.MGSimulation2Tab.Title = 'MG - Simulation 2';
            app.WTG = uiaxes(app.MGSimulation2Tab);
            title(app.WTG, 'WTG')
            xlabel(app.WTG, 't(s)')
            ylabel(app.WTG, 'Change in Power (J)')
            zlabel(app.WTG, 'Z')
            app.PV = uiaxes(app.MGSimulation2Tab);
            title(app.PV, 'PV')
            xlabel(app.PV, 't(s)')
            ylabel(app.PV, 'Change in Power (J)')
            zlabel(app.PV, 'Z')
            app.MT = uiaxes(app.MGSimulation2Tab);
            title(app.MT, 'MT')
            xlabel(app.MT, 't(s)')
            ylabel(app.MT, 'Change in Power (J)')
            zlabel(app.MT, 'Z')
            app.FC = uiaxes(app.MGSimulation2Tab);
            title(app.FC, 'FC')
            xlabel(app.FC, 't(s)')
            ylabel(app.FC, 'Change in Power (J)')
            zlabel(app.FC, 'Z')
            app.BES = uiaxes(app.MGSimulation2Tab);
            title(app.BES, 'BES')
            xlabel(app.BES, 't(s)')
            ylabel(app.BES, 'Change in Power (J)')
            zlabel(app.BES, 'Z')
            app.f = uiaxes(app.MGSimulation2Tab);
            title(app.f, 'f')
            xlabel(app.f, 't(s)')
            ylabel(app.f, 'System Controlled Output (Hz)')
            zlabel(app.f, 'Z')
            app.FCSimulationResult1Tab = uitab(app.TabGroup);
            app.FCSimulationResult1Tab.Title = 'FC - Simulation Result 1';
            app.EulersMethodUIAxes = uiaxes(app.FCSimulationResult1Tab);
            title(app.EulersMethodUIAxes, 'Results Integrated by Euler''s Method')
            xlabel(app.EulersMethodUIAxes, 'Time (s)')
            ylabel(app.EulersMethodUIAxes, 'current')
            zlabel(app.EulersMethodUIAxes, 'Z')
            app.ode45UIAxes = uiaxes(app.FCSimulationResult1Tab);
            title(app.ode45UIAxes, 'Results Integrated by ode45()')
            xlabel(app.ode45UIAxes, 'Time (s)')
            ylabel(app.ode45UIAxes, 'current')
            zlabel(app.ode45UIAxes, 'Z')
            app.EulerZoomSwitch = uiswitch(app.FCSimulationResult1Tab, 'slider');
            app.EulerZoomSwitch.Items = {'', ''};
            app.EulerZoomSwitch.ItemsData = [0 1];
            app.EulerZoomSwitch.ValueChangedFcn = createCallbackFcn(app, @EulerZoomSwitchValueChanged, true);
            app.EulerZoomSwitch.Value = 0;
            app.ode45ZoomSwitch = uiswitch(app.FCSimulationResult1Tab, 'slider');
            app.ode45ZoomSwitch.Items = {'', ''};
            app.ode45ZoomSwitch.ItemsData = [0 1];
            app.ode45ZoomSwitch.ValueChangedFcn = createCallbackFcn(app, @ode45ZoomSwitchValueChanged, true);
            app.ode45ZoomSwitch.Value = 0;
            app.FCSimulationResult2Tab = uitab(app.TabGroup);
            app.FCSimulationResult2Tab.Title = 'FC-Simulation Result 2';
            app.CompareVoltagesUIAxes = uiaxes(app.FCSimulationResult2Tab);
            title(app.CompareVoltagesUIAxes, 'Compare Vfc and Vo')
            xlabel(app.CompareVoltagesUIAxes, 'Time (s)')
            ylabel(app.CompareVoltagesUIAxes, 'Voltage (V)')
            zlabel(app.CompareVoltagesUIAxes, 'Z')
            app.CurrentRipplesUIAxes = uiaxes(app.FCSimulationResult2Tab);
            title(app.CurrentRipplesUIAxes, 'Current ripples and local maximas and minimas')
            xlabel(app.CurrentRipplesUIAxes, 'Time (s)')
            ylabel(app.CurrentRipplesUIAxes, 'current')
            zlabel(app.CurrentRipplesUIAxes, 'Z')
            app.TwoMethodVoltage = uiswitch(app.FCSimulationResult2Tab, 'slider');
            app.TwoMethodVoltage.Items = {'', ''};
            app.TwoMethodVoltage.ItemsData = [0 1];
            app.TwoMethodVoltage.ValueChangedFcn = createCallbackFcn(app, @TwoMethodVoltageValueChanged, true);
            app.TwoMethodVoltage.Value = 0;
            app.TwoMethodCurrent = uiswitch(app.FCSimulationResult2Tab, 'slider');
            app.TwoMethodCurrent.Items = {'', ''};
            app.TwoMethodCurrent.ItemsData = [0 1];
            app.TwoMethodCurrent.ValueChangedFcn = createCallbackFcn(app, @TwoMethodCurrentValueChanged, true);
            app.TwoMethodCurrent.Value = 0;
            % Show the figure after all components are created
            app.FinalProjectUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CompSim_FinalProjectGUI_Combo

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.FinalProjectUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.FinalProjectUIFigure)
        end
    end
end